classdef Daoham111 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Daoham                 matlab.ui.Figure
        yiEditField_3          matlab.ui.control.EditField
        NhpyiLabel             matlab.ui.control.Label
        xiEditField_2          matlab.ui.control.EditField
        NhpxiLabel             matlab.ui.control.Label
        Chn1trong2Switch       matlab.ui.control.Switch
        Chn1trong2SwitchLabel  matlab.ui.control.Label
        hsccdrop               matlab.ui.control.DropDown
        HsctctDropDownLabel    matlab.ui.control.Label
        ppdrop                 matlab.ui.control.DropDown
        PPDropDownLabel        matlab.ui.control.Label
        hEditField             matlab.ui.control.NumericEditField
        NhphEditFieldLabel     matlab.ui.control.Label
        xEditField             matlab.ui.control.NumericEditField
        NhpxEditFieldLabel     matlab.ui.control.Label
        KQlabel                matlab.ui.control.Label
        tinhbutton             matlab.ui.control.Button
        fxEditField            matlab.ui.control.EditField
        NhphmsEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Chn1trong2Switch, hsccdrop, ppdrop, tinhbutton
        function tinhbuttonPushed(app, event)
            xi=app.xiEditField_2.Value;
            yi=app.yiEditField_3.Value;
            xi=str2num(xi);
            yi=str2num(yi);
            fx=app.fxEditField.Value;

            h=app.hEditField.Value;
            x=app.xEditField.Value;

            in=app.Chn1trong2Switch.Value;

            pp=app.ppdrop.Value;

            hscc=app.hsccdrop.Value;
            
            dx = THDH(xi,yi,fx,h,x,in,pp,hscc);
            app.KQlabel.Text = num2str(dx)
        end

        % Callback function
        function ppdropValueChanged(app, event)
            value = app.ppdrop.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Daoham = uifigure('Visible', 'off');
            app.Daoham.Name = 'MATLAB App';
            app.NhphmsEditFieldLabel = uilabel(app.Daoham);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số ';
            app.fxEditField = uieditfield(app.Daoham, 'text');
            app.fxEditField.Value = 'asin(x)';
            app.tinhbutton = uibutton(app.Daoham, 'push');
            app.tinhbutton.ButtonPushedFcn = createCallbackFcn(app, @tinhbuttonPushed, true);
            app.tinhbutton.Text = 'Tính đạo hàm';
            app.KQlabel = uilabel(app.Daoham);
            app.KQlabel.Text = 'Kết quả ';
            app.NhpxEditFieldLabel = uilabel(app.Daoham);
            app.NhpxEditFieldLabel.Text = 'Nhập x';
            app.xEditField = uieditfield(app.Daoham, 'numeric');
            app.xEditField.Value = 0.5;
            app.NhphEditFieldLabel = uilabel(app.Daoham);
            app.NhphEditFieldLabel.Text = 'Nhập h';
            app.hEditField = uieditfield(app.Daoham, 'numeric');
            app.hEditField.Value = 0.2;
            app.PPDropDownLabel = uilabel(app.Daoham);
            app.PPDropDownLabel.Text = 'PP';
            app.ppdrop = uidropdown(app.Daoham);
            app.ppdrop.Items = {'Tiến', 'Lùi', 'Trung Tâm'};
            app.ppdrop.ValueChangedFcn = createCallbackFcn(app, @tinhbuttonPushed, true);
            app.ppdrop.Value = 'Tiến';
            app.HsctctDropDownLabel = uilabel(app.Daoham);
            app.HsctctDropDownLabel.Text = 'Hệ số cắt cụt';
            app.hsccdrop = uidropdown(app.Daoham);
            app.hsccdrop.Items = {'O(h^2)', 'O(h)'};
            app.hsccdrop.ValueChangedFcn = createCallbackFcn(app, @tinhbuttonPushed, true);
            app.hsccdrop.Value = 'O(h^2)';
            app.Chn1trong2SwitchLabel = uilabel(app.Daoham);
            app.Chn1trong2SwitchLabel.Text = 'Chọn 1 trong 2';
            app.Chn1trong2Switch = uiswitch(app.Daoham, 'slider');
            app.Chn1trong2Switch.Items = {'Fx', 'Xy'};
            app.Chn1trong2Switch.ValueChangedFcn = createCallbackFcn(app, @tinhbuttonPushed, true);
            app.Chn1trong2Switch.Value = 'Xy';
            app.NhpxiLabel = uilabel(app.Daoham);
            app.NhpxiLabel.Text = 'Nhập xi';
            app.xiEditField_2 = uieditfield(app.Daoham, 'text');
            app.xiEditField_2.Value = '0.1 0.3 0.5 0.7 0.9';
            app.NhpyiLabel = uilabel(app.Daoham);
            app.NhpyiLabel.Text = 'Nhập yi';
            app.yiEditField_3 = uieditfield(app.Daoham, 'text');
            app.yiEditField_3.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            % Show the figure after all components are created
            app.Daoham.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Daoham111

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Daoham)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Daoham)
        end
    end
end