classdef DOANMAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        KetQuaNghiem                   matlab.ui.control.EditField
        KtqunghimEditFieldLabel_2      matlab.ui.control.Label
        NhpkhongphnlynghimLabel_3      matlab.ui.control.Label
        TimNghiem                      matlab.ui.control.Button
        SaiSo_1                        matlab.ui.control.NumericEditField
        PhuongTrinh_2                  matlab.ui.control.EditField
        Nhpphngtrnh2Label              matlab.ui.control.Label
        PhanLyA                        matlab.ui.control.NumericEditField
        PhanLyB                        matlab.ui.control.NumericEditField
        PhuongTrinh_1                  matlab.ui.control.EditField
        ChonPPNghiem                   matlab.ui.control.DropDown
        ChnphngphptmnghimLabel         matlab.ui.control.Label
        SoLanLap                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        DoThiNghiem                    matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        KetQuaDaThuc                   matlab.ui.control.EditField
        KtqudonLabel_3                 matlab.ui.control.Label
        KetQuaNoiSuy                   matlab.ui.control.EditField
        KtqudonLabel_2                 matlab.ui.control.Label
        Noisuy                         matlab.ui.control.Button
        DuLieuX_1                      matlab.ui.control.EditField
        DuLieuY_1                      matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        ChonPPNoiSuy                   matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel   matlab.ui.control.Label
        CanNoiSuy                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        DoThiNoiSuy                    matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        GridLayout_3                   matlab.ui.container.GridLayout
        KetQuaDuDoan                   matlab.ui.control.EditField
        KtqudonLabel                   matlab.ui.control.Label
        KtquphngtrnhhiquyEditFieldLabel_2  matlab.ui.control.Label
        KetQuaHoiQuy                   matlab.ui.control.EditField
        HoiQuy                         matlab.ui.control.Button
        DuLieuX_2                      matlab.ui.control.EditField
        DuLieuY_2                      matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        ChonPPHoiQuy                   matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel   matlab.ui.control.Label
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        DuDoanHoiQuy                   matlab.ui.control.NumericEditField
        DoThiHoiQuy                    matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        GridLayout_4                   matlab.ui.container.GridLayout
        BuocH                          matlab.ui.control.NumericEditField
        NhpbchLabel                    matlab.ui.control.Label
        Hoac_1                         matlab.ui.control.ToggleSwitch
        KetQuaDaoHam                   matlab.ui.control.EditField
        KtquEditFieldLabel_2           matlab.ui.control.Label
        DaoHam                         matlab.ui.control.Button
        ChonPPDaoHam                   matlab.ui.control.DropDown
        HamSo_1                        matlab.ui.control.EditField
        DuLieuY_3                      matlab.ui.control.EditField
        DuLieuX_3                      matlab.ui.control.EditField
        SaiSo_2                        matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel       matlab.ui.control.Label
        ChnphngphpohmDropDownLabel     matlab.ui.control.Label
        NhphmsEditFieldLabel           matlab.ui.control.Label
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        CanDaoHam                      matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        TchphnTab                      matlab.ui.container.Tab
        GridLayout_5                   matlab.ui.container.GridLayout
        KetQuaTichPhan                 matlab.ui.control.EditField
        KtquEditFieldLabel_3           matlab.ui.control.Label
        NhpNEditFieldLabel_2           matlab.ui.control.Label
        NhpkhongphnlynghimLabel_2      matlab.ui.control.Label
        TichPhan                       matlab.ui.control.Button
        DuLieuX_4                      matlab.ui.control.EditField
        DuLieuY_4                      matlab.ui.control.EditField
        ChonPPTichPhan                 matlab.ui.control.DropDown
        HocLabel_2                     matlab.ui.control.Label
        HamSo_2                        matlab.ui.control.EditField
        ChnphngphptchphnDropDownLabel  matlab.ui.control.Label
        NhphmsEditField_2Label         matlab.ui.control.Label
        NhpdliuyEditField_4Label       matlab.ui.control.Label
        NDoan                          matlab.ui.control.NumericEditField
        CanA                           matlab.ui.control.NumericEditField
        CanB                           matlab.ui.control.NumericEditField
        NhpdliuxEditField_4Label       matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
        GridLayout_6                   matlab.ui.container.GridLayout
        Label_25                       matlab.ui.control.Label
        BnggiithiuvnhginhmLabel        matlab.ui.control.Label
        Label_24                       matlab.ui.control.Label
        Label_23                       matlab.ui.control.Label
        Label_22                       matlab.ui.control.Label
        Label_21                       matlab.ui.control.Label
        Label_20                       matlab.ui.control.Label
        CodeLabel_2                    matlab.ui.control.Label
        CodeLabel                      matlab.ui.control.Label
        AppLabel                       matlab.ui.control.Label
        WordLabel_2                    matlab.ui.control.Label
        AppLabel_2                     matlab.ui.control.Label
        WordLabel                      matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        NguynThanhTmLabel              matlab.ui.control.Label
        LinHipQucLabel_2               matlab.ui.control.Label
        TrnHongHiLabel                 matlab.ui.control.Label
        VXuncLabel                     matlab.ui.control.Label
        NguynQuangThnhtLabel_2         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        BiTntLabel                     matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TimNghiem
        function TimNghiemPushed(app, event)
            syms x;
            fx = matlabFunction(str2sym(app.PhuongTrinh_1.Value));
            fp = matlabFunction(str2sym(app.PhuongTrinh_2.Value));
            dfx = matlabFunction(diff(fx,x));
            ddfx = matlabFunction(diff(fx,x,2));

            a = app.PhanLyA.Value;
            b = app.PhanLyB.Value;
            es = app.SaiSo_1.Value;

            n=1000;
            test=a:abs(a-b)/n:b;
            for i =1:n/2
                if ddfx(test(i))*ddfx(test(n-i)) <=0
                    fprintf('Đạo hàm bậc 2 của fx không đồng điệu trên khoảng phân ly');
                    KQ = NaN;
                end
            end

            switch(app.ChonPPNghiem.Value)
                case 'Chia đôi'
                    n = 0;
                    while (1)
                        n = n+1;
                        c = (a+b)/2;
                        if fx(a)*fx(c) < 0
                            b = c;
                        else
                            a = c;
                        end
                        if abs(b-a) < es
                            KQ = c;
                            break;
                        end
                    end

                case 'Lặp'
                    n = 0;
                    c = (a + b)/2;
                    if fx(a)*fx(c) < 0
                        x0 = a;
                    else
                        x0 = b;
                    end
                    while (1)
                        n = n + 1;
                        x1 = fp(x0);
                        if abs(x1-x0) < es
                            KQ = x1;
                            break;
                        end
                        x0 = x1;
                    end

                case 'Newton (Tiếp tuyến)'
                    n = 0;
                    x0 = a;
                    if(fx(x0)*ddfx(x0) < 0)
                        x0 = b;
                    end
                    while(1)
                        n = n+1;
                        x1 = x0-fx(x0)/dfx(x0);
                        if (abs(x1-x0) < es)
                            KQ = x1;
                            break;
                        end
                        x0 = x1;
                    end
            end

            cla(app.DoThiNghiem);
            x = linspace(a, b, 100);
            grid(app.DoThiNghiem, 'on');
            hold(app.DoThiNghiem, 'on');
            plot(app.DoThiNghiem, x, zeros(1, 100), 'k');
            if(app.ChonPPNghiem.Value ~= "Chia đôi")
                plot(app.DoThiNghiem, zeros(1, 100), x, 'k');
            end
            if(app.ChonPPNghiem.Value == "Lặp")
                plot(app.DoThiNghiem, x, fp(x),'b-', x, x,'g-', KQ, fp(KQ), 'r*');
            else
                plot(app.DoThiNghiem, x, fx(x),'b-', KQ, fx(KQ), 'r*');
            end

            app.KetQuaNghiem.Value = num2str(KQ);
            app.SoLanLap.Value = n;

        end

        % Button pushed function: Noisuy
        function NoisuyPushed(app, event)
            x = [str2num(app.DuLieuX_1.Value)];
            y = [str2num(app.DuLieuY_1.Value)];
            if(length(x) ~= length(y))
                fprintf('x và y BẮT BUỘC phải cùng độ dài');
            end

            n = length(x);
            x1 = linspace(x(1), x(n), 100);
            ns = app.CanNoiSuy.Value;

            switch(app.ChonPPNoiSuy.Value)
                case 'Newton'
                    n = length(x);
                    N = zeros(n,n);
                    N(:, 1) = y';
              
                    for i=2:n
                        for j = 1:(n+1-i)
                            N(j,i) = (N(j+1,i-1)-N(j,i-1))/(x(j+i-1)-x(j));
                        end
                    end
                    a=N(1,:);
                    d=a(n);
                    for k = n-1:-1:1
                        d=[d a(k)] - [0 d*x(k)];
                    end

                case 'Lagrange'
                    L = zeros(n,n);
                    for i=1:n
                        V = 1;
                        for j = 1:n
                            if i ~= j
                                V = conv(V,poly(x(j)))/(x(i)-x(j));
                            end
                        end
                        L(i,:) = V;
                    end
                    d = y*L;
                                                           
            end

            PT = polyval(d,x1);
            KQ = polyval(d,ns);
            
            cla(app.DoThiNoiSuy);
            grid(app.DoThiNoiSuy, 'on');
            plot(app.DoThiNoiSuy, x, y, 'bo', x1, PT, 'g-', ns, KQ, 'r*');

            PT=[];
            for i=1:n
                if(d(i)~=0)        
                    if(i==n)
                        D = num2str(d(i));
                    elseif(i==n-1)
                        D = strcat(num2str(d(i)), 'x');
                    else
                        D = strcat(num2str(d(i)), 'x^', num2str(n-i));
                    end
                    if(i>1 && d(i)>0)
                        PT = strcat(PT,'+',D);
                    else
                        PT = strcat(PT,D);
                    end
                end
            end

            app.KetQuaDaThuc.Value = num2str(PT);
            app.KetQuaNoiSuy.Value = num2str(KQ);

        end

        % Button pushed function: HoiQuy
        function HoiQuyPushed(app, event)
            x = [str2num(app.DuLieuX_2.Value)];
            y = [str2num(app.DuLieuY_2.Value)];
            if(length(x) ~= length(y))
                fprintf('x và y BẮT BUỘC phải cùng độ dài');
            end

            n = length(x);
            x1 = linspace(x(1), x(n), 100);
            hq = app.DuDoanHoiQuy.Value;

            switch(app.ChonPPHoiQuy.Value)
                case 'Tuyến tính(y=ax+b)'
                    sigma_x2 = x.*x;
                    sigma_xy = x.*y;
                    a = (n*sum(sigma_xy)-sum(x)*sum(y))/(n*sum(sigma_x2)-sum(x)^2);
                    b = sum(y)/n-a*sum(x)/n;

                    A = a;
                    B = b;

                    PT = A.*x1+B;
                    KQ = A.*hq+B;
                    
                case 'Lũy thừa(y=ae^bx)'
                    Y=log(y);
                    sigma_x2= x.*x;
                    sigma_xy= x.*Y;
                    a = (n*sum(sigma_xy)-sum(x)*sum(Y))/(n*sum(sigma_x2)-sum(x)^2);
                    b = sum(Y)/n-a*sum(x)/n;

                    A = exp(b);
                    B = a;

                    PT = A.*exp(B.*x1);
                    KQ = A.*exp(B.*hq);
                    
                case 'Mũ(y=ax^b)'
                    Y = log10(y);
                    X = log10(x);
                    sigma_x2 = X.*X;
                    sigma_xy = X.*Y;
                    a = (n*sum(sigma_xy)-sum(X)*sum(Y))/(n*sum(sigma_x2)-sum(X)^2);
                    b = sum(Y)/n-a*sum(X)/n;

                    A = 10.^b;
                    B = a;

                    PT = A.*x1.^B;
                    KQ = A.*hq.^B;

                case 'Logarit(y=ax/b+x)'
                    Y = 1./y;
                    X = 1./x;
                    sigma_x2 = X.*X;
                    sigma_xy = X.*Y;
                    a = (n*sum(sigma_xy)-sum(X)*sum(Y))/(n*sum(sigma_x2)-sum(X)^2);
                    b = sum(Y)/n-a*sum(X)/n;

                    A = 1/b;
                    B = a*A;

                    PT = A.*x1./(B+x1);
                    KQ = A.*hq./(B+hq);

            end

            cla(app.DoThiHoiQuy);
            grid(app.DoThiHoiQuy, 'on');
            plot(app.DoThiHoiQuy, x, y, 'bo', x1, PT, 'g-', hq, KQ, 'r*');         
            
            app.KetQuaHoiQuy.Value = ['a = ' num2str(A) '; b = ' num2str(B)];
            app.KetQuaDuDoan.Value = num2str(KQ);

        end

        % Button pushed function: DaoHam
        function DaoHamPushed(app, event)
            x = [str2num(app.DuLieuX_3.Value)];
            y = [str2num(app.DuLieuY_3.Value)];
            if(length(x) ~= length(y))
                fprintf('x và y BẮT BUỘC phải cùng độ dài');
            end
            
            dh = app.CanDaoHam.Value;
            switch(app.Hoac_1.Value)
                case 'Dữ liệu'
                    fx = y;
                    h = x(2)-x(1);
                    h1 = 1;
                    dh = floor((dh-x(1))/h)+1;

                case 'Hàm số'
                    fx = matlabFunction(str2sym(app.HamSo_1.Value));
                    h = app.BuocH.Value;
                    h1 = h;

            end
            
            switch(app.SaiSo_2.Value)
                case 'O(h^2)'
                    if app.ChonPPDaoHam.Value == "Xấp xỉ tiến"
                        KQ = (-fx(dh+2*h1) + 4*fx(dh+h1) - 3*fx(dh)) / (2*h);
                    elseif app.ChonPPDaoHam.Value == "Xấp xỉ lùi"
                        KQ = (3*fx(dh) - 4*fx(dh-h1) + fx(dh-2*h1)) / (2*h);
                    else
                        KQ = (fx(dh+h1) - fx(dh-h1)) / (2*h);
                    end

                case 'O(h)'
                    if app.ChonPPDaoHam.Value == "Xấp xỉ tiến"
                        KQ = (fx(dh+h1) - fx(dh)) / (h);
                    elseif app.ChonPPDaoHam.Value == "Xấp xỉ lùi"
                        KQ = (fx(dh) - fx(dh-h1)) / (h);
                    else
                        fprintf('Vui lòng chỉnh lại phương pháp chọn Đạo Hàm');
                        KQ = NaN;
                    end

            end

            app.KetQuaDaoHam.Value = num2str(KQ);

        end

        % Button pushed function: TichPhan
        function TichPhanButtonPushed(app, event)
            x = [str2num(app.DuLieuX_4.Value)];
            y = [str2num(app.DuLieuY_4.Value)];
            if(length(x) ~= length(y))
                fprintf('x và y BẮT BUỘC phải cùng độ dài');
            end

            fx = matlabFunction(str2sym(app.HamSo_2.Value));
            N = app.NDoan.Value;
            a = app.CanA.Value;
            b = app.CanB.Value;

            switch(app.ChonPPTichPhan.Value)
                case 'Hình thang'
                    h = (b - a) / N;
                    KQ = (h/2)*(fx(a)+fx(b)+2*sum(fx(a+h:h:b-h)));

                case 'Simpson 1/3'
                    if mod(N, 2) ~= 0
                        N = N + 1;
                    end
                    h = (b - a) / N;
                    x = a + (0:N) * h;
                    fx = feval(fx, x);
                    KQ = (h/3)*(fx(1)+fx(N+1)+4*sum(fx(2:2:N))+2*sum(fx(3:2:N-1)));

                case 'Simpson 3/8'
                    if mod(N, 3) ~= 0
                        N = N + 1;
                    end
                    h = (b - a) / N;
                    x = a + (0:N) * h;
                    fx = feval(fx, x);
                    KQ = (3*h/8)*(fx(1)+fx(N+1)+3*sum(fx(2:3:N))+3*sum(fx(3:3:N-1))+2*sum(fx(4:3:N-2)));

            end

            app.KetQuaTichPhan.Value = num2str(KQ);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.GridLayout = uigridlayout(app.NghimTab);
            app.DoThiNghiem = uiaxes(app.GridLayout);
            title(app.DoThiNghiem, 'Vẽ đồ thị hàm số')
            xlabel(app.DoThiNghiem, 'X')
            ylabel(app.DoThiNghiem, 'Y')
            zlabel(app.DoThiNghiem, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.GridLayout);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpsaischophpEditFieldLabel = uilabel(app.GridLayout);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.SlnlpEditFieldLabel = uilabel(app.GridLayout);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.GridLayout, 'numeric');
            app.ChnphngphptmnghimLabel = uilabel(app.GridLayout);
            app.ChnphngphptmnghimLabel.Text = 'Chọn phương pháp tìm nghiệm';
            app.ChonPPNghiem = uidropdown(app.GridLayout);
            app.ChonPPNghiem.Items = {'Chia đôi', 'Lặp', 'Newton (Tiếp tuyến)'};
            app.ChonPPNghiem.Value = 'Chia đôi';
            app.PhuongTrinh_1 = uieditfield(app.GridLayout, 'text');
            app.PhuongTrinh_1.Value = 'x - sin(x) - 0.25';
            app.PhanLyB = uieditfield(app.GridLayout, 'numeric');
            app.PhanLyB.Value = 2;
            app.PhanLyA = uieditfield(app.GridLayout, 'numeric');
            app.PhanLyA.Value = -1;
            app.Nhpphngtrnh2Label = uilabel(app.GridLayout);
            app.Nhpphngtrnh2Label.Text = 'Nhập phương trình 2';
            app.PhuongTrinh_2 = uieditfield(app.GridLayout, 'text');
            app.PhuongTrinh_2.Value = 'sin(x) + 0.25';
            app.SaiSo_1 = uieditfield(app.GridLayout, 'numeric');
            app.SaiSo_1.Value = 0.005;
            app.TimNghiem = uibutton(app.GridLayout, 'push');
            app.TimNghiem.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemPushed, true);
            app.TimNghiem.Text = 'Tìm Nghiệm';
            app.NhpkhongphnlynghimLabel_3 = uilabel(app.GridLayout);
            app.NhpkhongphnlynghimLabel_3.Text = 'Nhập khoảng phân ly';
            app.KtqunghimEditFieldLabel_2 = uilabel(app.GridLayout);
            app.KtqunghimEditFieldLabel_2.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.GridLayout, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.GridLayout_2 = uigridlayout(app.NisuyTab);
            app.DoThiNoiSuy = uiaxes(app.GridLayout_2);
            title(app.DoThiNoiSuy, 'Vẽ đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.DoThiNoiSuy, 'X')
            ylabel(app.DoThiNoiSuy, 'Y')
            zlabel(app.DoThiNoiSuy, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.CanNoiSuy = uieditfield(app.GridLayout_2, 'numeric');
            app.CanNoiSuy.Value = 5;
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.GridLayout_2);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonPPNoiSuy = uidropdown(app.GridLayout_2);
            app.ChonPPNoiSuy.Items = {'Newton', 'Lagrange'};
            app.ChonPPNoiSuy.Value = 'Newton';
            app.NhpdliuyEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DuLieuY_1 = uieditfield(app.GridLayout_2, 'text');
            app.DuLieuY_1.Value = '17 17.5 76 210.5 1970';
            app.DuLieuX_1 = uieditfield(app.GridLayout_2, 'text');
            app.DuLieuX_1.Value = '1 2 3 4 7';
            app.Noisuy = uibutton(app.GridLayout_2, 'push');
            app.Noisuy.ButtonPushedFcn = createCallbackFcn(app, @NoisuyPushed, true);
            app.Noisuy.Text = 'Nội suy';
            app.KtqudonLabel_2 = uilabel(app.GridLayout_2);
            app.KtqudonLabel_2.Text = 'Kết quả nội suy';
            app.KetQuaNoiSuy = uieditfield(app.GridLayout_2, 'text');
            app.KtqudonLabel_3 = uilabel(app.GridLayout_2);
            app.KtqudonLabel_3.Text = 'Kết quả đa thức nội suy';
            app.KetQuaDaThuc = uieditfield(app.GridLayout_2, 'text');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.GridLayout_3 = uigridlayout(app.HiquyTab);
            app.DoThiHoiQuy = uiaxes(app.GridLayout_3);
            title(app.DoThiHoiQuy, 'Vẽ đồ thị hàm số hồi quy và dữ liệu thực')
            xlabel(app.DoThiHoiQuy, 'X')
            ylabel(app.DoThiHoiQuy, 'Y')
            zlabel(app.DoThiHoiQuy, 'Z')
            app.DuDoanHoiQuy = uieditfield(app.GridLayout_3, 'numeric');
            app.DuDoanHoiQuy.Value = 2.5;
            app.NhpdliuxEditField_2Label = uilabel(app.GridLayout_3);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.GridLayout_3);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.GridLayout_3);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChonPPHoiQuy = uidropdown(app.GridLayout_3);
            app.ChonPPHoiQuy.Items = {'Tuyến tính(y=ax+b)', 'Lũy thừa(y=ae^bx)', 'Mũ(y=ax^b)', 'Logarit(y=ax/b+x)'};
            app.ChonPPHoiQuy.Value = 'Tuyến tính(y=ax+b)';
            app.NhpdliuyEditField_2Label = uilabel(app.GridLayout_3);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.DuLieuY_2 = uieditfield(app.GridLayout_3, 'text');
            app.DuLieuY_2.Value = '0.2 0.7 3.4 4.8 5.6';
            app.DuLieuX_2 = uieditfield(app.GridLayout_3, 'text');
            app.DuLieuX_2.Value = '1 2 3 4 5';
            app.HoiQuy = uibutton(app.GridLayout_3, 'push');
            app.HoiQuy.ButtonPushedFcn = createCallbackFcn(app, @HoiQuyPushed, true);
            app.HoiQuy.Text = 'Hồi quy';
            app.KetQuaHoiQuy = uieditfield(app.GridLayout_3, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel_2 = uilabel(app.GridLayout_3);
            app.KtquphngtrnhhiquyEditFieldLabel_2.Text = 'Kết quả phương trình hồi quy';
            app.KtqudonLabel = uilabel(app.GridLayout_3);
            app.KtqudonLabel.Text = 'Kết quả hồi quy';
            app.KetQuaDuDoan = uieditfield(app.GridLayout_3, 'text');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.GridLayout_4 = uigridlayout(app.ohmTab);
            app.NhpdliuxEditField_3Label = uilabel(app.GridLayout_4);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.GridLayout_4);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.CanDaoHam = uieditfield(app.GridLayout_4, 'numeric');
            app.CanDaoHam.Value = 0.5;
            app.NhpdliuyEditField_3Label = uilabel(app.GridLayout_4);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhphmsEditFieldLabel = uilabel(app.GridLayout_4);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.ChnphngphpohmDropDownLabel = uilabel(app.GridLayout_4);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChngitrsaisDropDownLabel = uilabel(app.GridLayout_4);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaiSo_2 = uidropdown(app.GridLayout_4);
            app.SaiSo_2.Items = {'O(h)', 'O(h^2)'};
            app.SaiSo_2.Value = 'O(h)';
            app.DuLieuX_3 = uieditfield(app.GridLayout_4, 'text');
            app.DuLieuX_3.Value = '0.1 0.3 0.5 0.7 0.9';
            app.DuLieuY_3 = uieditfield(app.GridLayout_4, 'text');
            app.DuLieuY_3.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.HamSo_1 = uieditfield(app.GridLayout_4, 'text');
            app.HamSo_1.Value = 'asin(x)';
            app.ChonPPDaoHam = uidropdown(app.GridLayout_4);
            app.ChonPPDaoHam.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChonPPDaoHam.Value = 'Xấp xỉ tiến';
            app.DaoHam = uibutton(app.GridLayout_4, 'push');
            app.DaoHam.ButtonPushedFcn = createCallbackFcn(app, @DaoHamPushed, true);
            app.DaoHam.Text = 'Đạo hàm';
            app.KtquEditFieldLabel_2 = uilabel(app.GridLayout_4);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQuaDaoHam = uieditfield(app.GridLayout_4, 'text');
            app.Hoac_1 = uiswitch(app.GridLayout_4, 'toggle');
            app.Hoac_1.Items = {'Dữ liệu', 'Hàm số'};
            app.Hoac_1.Value = 'Dữ liệu';
            app.NhpbchLabel = uilabel(app.GridLayout_4);
            app.NhpbchLabel.Text = 'Nhập bước h';
            app.BuocH = uieditfield(app.GridLayout_4, 'numeric');
            app.BuocH.Value = 0.25;
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.GridLayout_5 = uigridlayout(app.TchphnTab);
            app.NhpdliuxEditField_4Label = uilabel(app.GridLayout_5);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x';
            app.CanB = uieditfield(app.GridLayout_5, 'numeric');
            app.CanB.Value = 1;
            app.CanA = uieditfield(app.GridLayout_5, 'numeric');
            app.NDoan = uieditfield(app.GridLayout_5, 'numeric');
            app.NDoan.Value = 10;
            app.NhpdliuyEditField_4Label = uilabel(app.GridLayout_5);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y';
            app.NhphmsEditField_2Label = uilabel(app.GridLayout_5);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.ChnphngphptchphnDropDownLabel = uilabel(app.GridLayout_5);
            app.ChnphngphptchphnDropDownLabel.Text = 'Chọn phương pháp tích phân';
            app.HamSo_2 = uieditfield(app.GridLayout_5, 'text');
            app.HamSo_2.Value = 'x^3*sin(x)';
            app.HocLabel_2 = uilabel(app.GridLayout_5);
            app.HocLabel_2.Text = 'Hoặc';
            app.ChonPPTichPhan = uidropdown(app.GridLayout_5);
            app.ChonPPTichPhan.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChonPPTichPhan.Value = 'Hình thang';
            app.DuLieuY_4 = uieditfield(app.GridLayout_5, 'text');
            app.DuLieuX_4 = uieditfield(app.GridLayout_5, 'text');
            app.TichPhan = uibutton(app.GridLayout_5, 'push');
            app.TichPhan.ButtonPushedFcn = createCallbackFcn(app, @TichPhanButtonPushed, true);
            app.TichPhan.Text = 'Tích phân';
            app.NhpkhongphnlynghimLabel_2 = uilabel(app.GridLayout_5);
            app.NhpkhongphnlynghimLabel_2.Text = 'Nhập Cận';
            app.NhpNEditFieldLabel_2 = uilabel(app.GridLayout_5);
            app.NhpNEditFieldLabel_2.Text = 'Nhập N';
            app.KtquEditFieldLabel_3 = uilabel(app.GridLayout_5);
            app.KtquEditFieldLabel_3.Text = 'Kết quả';
            app.KetQuaTichPhan = uieditfield(app.GridLayout_5, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.GridLayout_6 = uigridlayout(app.GiithiunhmTab);
            app.UITable = uitable(app.GridLayout_6);
            app.UITable.ColumnName = {'Tên thành viên'; ''; 'MSSV'; ''; 'Nhiệm vụ'; ''; 'Tự đánh giá'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.BiTntLabel = uilabel(app.GridLayout_6);
            app.BiTntLabel.Interpreter = 'html';
            app.BiTntLabel.Text = 'Bùi Tấn Đạt';
            app.Label = uilabel(app.GridLayout_6);
            app.Label.Interpreter = 'html';
            app.Label.Text = '21207133';
            app.NguynQuangThnhtLabel_2 = uilabel(app.GridLayout_6);
            app.NguynQuangThnhtLabel_2.Interpreter = 'html';
            app.NguynQuangThnhtLabel_2.Text = '    Nguyễn Quang Thành Đạt';
            app.VXuncLabel = uilabel(app.GridLayout_6);
            app.VXuncLabel.Interpreter = 'html';
            app.VXuncLabel.Text = 'Vũ Xuân Đức';
            app.TrnHongHiLabel = uilabel(app.GridLayout_6);
            app.TrnHongHiLabel.Interpreter = 'html';
            app.TrnHongHiLabel.Text = 'Trần Hoàng Hải';
            app.LinHipQucLabel_2 = uilabel(app.GridLayout_6);
            app.LinHipQucLabel_2.Interpreter = 'html';
            app.LinHipQucLabel_2.Text = 'Liên Hiệp Quốc';
            app.NguynThanhTmLabel = uilabel(app.GridLayout_6);
            app.NguynThanhTmLabel.Interpreter = 'html';
            app.NguynThanhTmLabel.Text = 'Nguyễn Thanh Tâm';
            app.Label_6 = uilabel(app.GridLayout_6);
            app.Label_6.Interpreter = 'html';
            app.Label_6.Text = '21207140';
            app.Label_7 = uilabel(app.GridLayout_6);
            app.Label_7.Interpreter = 'html';
            app.Label_7.Text = '21207150';
            app.Label_9 = uilabel(app.GridLayout_6);
            app.Label_9.Interpreter = 'html';
            app.Label_9.Text = '21207215';
            app.Label_10 = uilabel(app.GridLayout_6);
            app.Label_10.Interpreter = 'html';
            app.Label_10.Text = '21207250';
            app.Label_11 = uilabel(app.GridLayout_6);
            app.Label_11.Interpreter = 'html';
            app.Label_11.Text = '21207134';
            app.WordLabel = uilabel(app.GridLayout_6);
            app.WordLabel.Interpreter = 'html';
            app.WordLabel.Text = 'Word';
            app.AppLabel_2 = uilabel(app.GridLayout_6);
            app.AppLabel_2.Interpreter = 'html';
            app.AppLabel_2.Text = 'App';
            app.WordLabel_2 = uilabel(app.GridLayout_6);
            app.WordLabel_2.Interpreter = 'html';
            app.WordLabel_2.Text = 'Word';
            app.AppLabel = uilabel(app.GridLayout_6);
            app.AppLabel.Interpreter = 'html';
            app.AppLabel.Text = 'App';
            app.CodeLabel = uilabel(app.GridLayout_6);
            app.CodeLabel.Interpreter = 'html';
            app.CodeLabel.Text = 'Code';
            app.CodeLabel_2 = uilabel(app.GridLayout_6);
            app.CodeLabel_2.Interpreter = 'html';
            app.CodeLabel_2.Text = 'Code';
            app.Label_20 = uilabel(app.GridLayout_6);
            app.Label_20.Interpreter = 'html';
            app.Label_20.Text = '100%';
            app.Label_21 = uilabel(app.GridLayout_6);
            app.Label_21.Interpreter = 'html';
            app.Label_21.Text = '100%';
            app.Label_22 = uilabel(app.GridLayout_6);
            app.Label_22.Interpreter = 'html';
            app.Label_22.Text = '100%';
            app.Label_23 = uilabel(app.GridLayout_6);
            app.Label_23.Interpreter = 'html';
            app.Label_23.Text = '100%';
            app.Label_24 = uilabel(app.GridLayout_6);
            app.Label_24.Interpreter = 'html';
            app.Label_24.Text = '100%';
            app.BnggiithiuvnhginhmLabel = uilabel(app.GridLayout_6);
            app.BnggiithiuvnhginhmLabel.Interpreter = 'tex';
            app.BnggiithiuvnhginhmLabel.Text = 'Bảng giới thiệu và đánh giá nhóm';
            app.Label_25 = uilabel(app.GridLayout_6);
            app.Label_25.Interpreter = 'html';
            app.Label_25.Text = '100%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOANMAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end